classdef FSKReceiverApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ClearButton                  matlab.ui.control.Button
        StartReceivingButton         matlab.ui.control.Button
        SamplesEditField             matlab.ui.control.NumericEditField
        SamplesEditFieldLabel        matlab.ui.control.Label
        ValidationnumEditField       matlab.ui.control.NumericEditField
        ValidationnumEditFieldLabel  matlab.ui.control.Label
        MessageEditField             matlab.ui.control.EditField
        MessageEditFieldLabel        matlab.ui.control.Label
        DistanceEditField            matlab.ui.control.NumericEditField
        DistanceEditFieldLabel       matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        UpdateformulaButton          matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        EstimateddistancemLabel      matlab.ui.control.Label
        ATMCheckBox                  matlab.ui.control.CheckBox
        MovingMedianCheckBox         matlab.ui.control.CheckBox
        KalmanCheckBox               matlab.ui.control.CheckBox
        RawCheckBox                  matlab.ui.control.CheckBox
        SavedataButton               matlab.ui.control.StateButton
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Receiver
        Values
        FilteredValues
        FilteredMedValues
        FilteredATM
        Param
        plotRaw
        plotKalman
        plotMedian
        plotATM
        measNum = 0    % number of measurement: first, second, many more
        d0
        RSSI0
        n
        RSSI
        measuredDist = []
        measuredRSSI = []
    end
    
    methods (Access = private)
        
        function plotData(app)
            app.plotRaw = plot(app.UIAxes, app.Values, 'DisplayName', 'Raw Data', 'Marker', 'none');
            hold(app.UIAxes, 'on');
            app.plotKalman = plot(app.UIAxes, app.FilteredValues, 'DisplayName', 'Kalman Data', 'Marker', 'none');
            hold(app.UIAxes, 'on');
            app.plotMedian = plot(app.UIAxes, app.FilteredMedValues, 'DisplayName', 'Moving Median Data', 'Marker', 'none');
            hold(app.UIAxes, 'on');
            app.plotATM = plot(app.UIAxes, app.FilteredATM, 'DisplayName', 'ATM data', 'Marker', 'none');
            hold(app.UIAxes, 'off');
            legend(app.UIAxes, 'show');
            title(app.UIAxes, 'RSSI Measurements');
            xlabel(app.UIAxes, 'Measurement Index');
            ylabel(app.UIAxes, 'RSSI, dB');
            
            app.SavedataButton.Enable = 'on';
            app.RawCheckBox.Visible =  'on';
            app.MovingMedianCheckBox.Visible = 'on';
            app.KalmanCheckBox.Visible = 'on';
            app.ATMCheckBox.Visible = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartReceivingButton
        function startReceiver(app, event)
            message = app.MessageEditField.Value;
            validationNum = app.ValidationnumEditField.Value;
            samples = app.SamplesEditField.Value;

            % Simulate data reception using the existing function
            app.Receiver = fsk_receive(message, validationNum, samples);
            [app.Values, valid] = app.Receiver.receive;
            app.Param = app.Receiver.prmFSKReceiver;
            if valid
                app.FilteredValues = kalman_test(app.Values);
                app.FilteredMedValues = movmedian(app.Values, 20);
                app.FilteredATM = AlphaTrimMean(app.Values, 11, 0.2);

                meanKalmanRSSI = mean(app.FilteredValues);
                app.RSSI = - meanKalmanRSSI;
           
                plotData(app);

                if app.measNum >= 2
                    estDist = app.d0 * 10 ^ ((app.RSSI0 - app.RSSI) / (10 * app.n));
                    app.Label.Text = num2str(estDist);
                end
            end
        end

        % Value changed function: SavedataButton
        function saveData(app, event)
            currentDateTime = datetime('now', 'Format', 'yyyyMMdd_HHmmss');
            directory = '/home/star13/ws/measurements/';
            distance = app.DistanceEditField.Value;
            dist = int2str(distance);
            baseFilename = fullfile(directory, ['g', num2str(app.Param.PlutoGain), ...
                '_f', strrep(sprintf('%.1e', app.Param.PlutoCenterFrequency), '.', '-'), ...
                '_d', dist, '_', char(currentDateTime)]);

            fig = figure;
            fig.Visible = 'off';
            fig.Position = [34, 41, 1109, 794];
            figAxes = axes(fig);

            allChildren = app.UIAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes);
            figAxes.XLim = app.UIAxes.XLim;
            figAxes.YLim = app.UIAxes.YLim;
            
            copyUIAxes(app.UIAxes, figAxes);
         
            figAxes.Units = app.UIAxes.Units;
            figAxes.ActivePositionProperty = 'position';
            figAxes.Position = [90, 73, 979, 677];
            figAxes.XGrid = 'on';
            figAxes.YGrid = 'on';

            saveas(fig, [baseFilename, '_raw.png']);
            dataTable = table(app.Values(:), app.FilteredValues(:),...
                app.FilteredMedValues(:), app.FilteredATM(:), ...
                'VariableNames', {'Raw Data', 'Kalman Data', 'Moving Average Data', 'app.FilteredATM'});

            writetable(dataTable, [baseFilename, '_raw.csv'], 'Delimiter', ',');

            delete(fig);

            idx = find(app.measuredDist== distance, 1);

            if isempty(idx)
                app.measuredDist = [app.measuredDist, distance];
                app.measuredRSSI = [app.measuredRSSI, app.RSSI];
            else
                app.MeasuredRSSIValues(idx) = app.RSSI;
            end

            if (app.measNum == 0) || (distance == app.d0)
                app.RSSI0 = app.RSSI;
                app.d0 = distance;
                app.measNum = 1;

            elseif (app.measNum == 1)
                x = log10(distance / app.d0);
                y = app.RSSI - app.RSSI0;
                app.n = y / (x * (-10));
                app.measNum = 2;
                app.UpdateformulaButton.Visible = 'on';
            end
        end

        % Value changed function: RawCheckBox
        function checkboxRaw(app, event)
            value = app.RawCheckBox.Value;
            if value
                app.plotRaw.Visible = 'on';  % Show the raw data plot
            else
                app.plotRaw.Visible = 'off';  % Hide the raw data plot
            end
        end

        % Value changed function: KalmanCheckBox
        function checkboxKalman(app, event)
            value = app.KalmanCheckBox.Value;
            if value
                app.plotKalman.Visible = 'on';  % Show the raw data plot
            else
                app.plotKalman.Visible = 'off';  % Hide the raw data plot
            end
        end

        % Value changed function: MovingMedianCheckBox
        function checkboxMedian(app, event)
            value = app.MovingMedianCheckBox.Value;
            if value
                app.plotMedian.Visible = 'on';  % Show the raw data plot
            else
                app.plotMedian.Visible = 'off';  % Hide the raw data plot
            end
        end

        % Value changed function: ATMCheckBox
        function checkboxATM(app, event)
            value = app.ATMCheckBox.Value;
            if value
                app.plotATM.Visible = 'on';  % Show the raw data plot
            else
                app.plotATM.Visible = 'off';  % Hide the raw data plot
            end
        end

        % Button pushed function: ClearButton
        function clearPlot(app, event)
            app.Receiver.releaseResources();
            cla(app.UIAxes);
        end

        % Button pushed function: UpdateformulaButton
        function updateFormula(app, event)
            if app.measNum >= 2
                logDistances = log10(app.measuredDist / app.d0);
                rssiDifferences = app.measuredRSSI - app.RSSI0;
                m = sum(logDistances .* rssiDifferences) / sum(logDistances .^ 2);
                app.n = -m / 10;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {511, 511};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {261, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DistanceEditFieldLabel = uilabel(app.LeftPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DistanceEditField.Limits = [0 2000];
            app.DistanceEditField.Tag = 'distance';
            app.DistanceEditField.Tooltip = {'The distance in meters of the target from the node'};
            app.DistanceEditField.Value = 10;
            app.MessageEditFieldLabel = uilabel(app.LeftPanel);
            app.MessageEditFieldLabel.Text = 'Message';
            app.MessageEditField = uieditfield(app.LeftPanel, 'text');
            app.MessageEditField.Tag = 'message';
            app.MessageEditField.Tooltip = {'Write the identification message to be received from the node'};
            app.MessageEditField.Value = 'Just check';
            app.ValidationnumEditFieldLabel = uilabel(app.LeftPanel);
            app.ValidationnumEditFieldLabel.Text = 'Validation num';
            app.ValidationnumEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ValidationnumEditField.Limits = [0 Inf];
            app.ValidationnumEditField.Tag = 'validationNum';
            app.ValidationnumEditField.Tooltip = {'Write the number of the messages to be recieved from the node to validate it'};
            app.ValidationnumEditField.Value = 5;
            app.SamplesEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplesEditField.Limits = [0 Inf];
            app.SamplesEditField.Tag = 'samples';
            app.SamplesEditField.Tooltip = {'Number of samples to take'};
            app.SamplesEditField.Value = 50;
            app.StartReceivingButton = uibutton(app.LeftPanel, 'push');
            app.StartReceivingButton.ButtonPushedFcn = createCallbackFcn(app, @startReceiver, true);
            app.StartReceivingButton.Text = 'Start Receiving';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearPlot, true);
            app.ClearButton.Text = 'Clear';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'RSSI measurements';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SavedataButton = uibutton(app.RightPanel, 'state');
            app.SavedataButton.ValueChangedFcn = createCallbackFcn(app, @saveData, true);
            app.SavedataButton.Enable = 'off';
            app.SavedataButton.Tooltip = {'Saves raw data in a CSV file, and the plot as a png'};
            app.SavedataButton.Text = 'Save data';
            app.RawCheckBox = uicheckbox(app.RightPanel);
            app.RawCheckBox.ValueChangedFcn = createCallbackFcn(app, @checkboxRaw, true);
            app.RawCheckBox.Visible = 'off';
            app.RawCheckBox.Text = 'Raw';
            app.RawCheckBox.Value = true;
            app.KalmanCheckBox = uicheckbox(app.RightPanel);
            app.KalmanCheckBox.ValueChangedFcn = createCallbackFcn(app, @checkboxKalman, true);
            app.KalmanCheckBox.Visible = 'off';
            app.KalmanCheckBox.Text = 'Kalman';
            app.KalmanCheckBox.Value = true;
            app.MovingMedianCheckBox = uicheckbox(app.RightPanel);
            app.MovingMedianCheckBox.ValueChangedFcn = createCallbackFcn(app, @checkboxMedian, true);
            app.MovingMedianCheckBox.Visible = 'off';
            app.MovingMedianCheckBox.Text = 'Moving Median';
            app.MovingMedianCheckBox.Value = true;
            app.ATMCheckBox = uicheckbox(app.RightPanel);
            app.ATMCheckBox.ValueChangedFcn = createCallbackFcn(app, @checkboxATM, true);
            app.ATMCheckBox.Visible = 'off';
            app.ATMCheckBox.Text = 'ATM';
            app.ATMCheckBox.Value = true;
            app.EstimateddistancemLabel = uilabel(app.RightPanel);
            app.EstimateddistancemLabel.Text = 'Estimated distance (m):';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            app.UpdateformulaButton = uibutton(app.RightPanel, 'push');
            app.UpdateformulaButton.ButtonPushedFcn = createCallbackFcn(app, @updateFormula, true);
            app.UpdateformulaButton.Visible = 'off';
            app.UpdateformulaButton.Tooltip = {'Update distance measurement formula based on all values'};
            app.UpdateformulaButton.Text = 'Update formula';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FSKReceiverApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end