classdef FSKTransmitterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StartTransmitButton       matlab.ui.control.Button
        MessagenumEditField       matlab.ui.control.NumericEditField
        MessagenumEditFieldLabel  matlab.ui.control.Label
        MessageEditField          matlab.ui.control.EditField
        MessageEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        start = true;
        transmitter
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartTransmitButton
        function StartStopTransmit(app, event)
            if app.start 
                app.StartTransmitButton.Text = {'Stop'; 'Transmit'};
                message = app.MessageEditField.Value;
                num = app.MessagenumEditField.Value;
                app.transmitter = fsk_transmit(message, num);
                app.transmitter.transmitContinuous;
                app.start = false;
            else
                app.transmitter.stopTransmission();
                app.start = true;
                app.StartTransmitButton.Text = {'Start'; 'Transmit'};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MessageEditFieldLabel = uilabel(app.UIFigure);
            app.MessageEditFieldLabel.Text = 'Message';
            app.MessageEditField = uieditfield(app.UIFigure, 'text');
            app.MessageEditField.Tooltip = {'The message to be sent to the node'};
            app.MessageEditField.Value = 'Just check';
            app.MessagenumEditFieldLabel = uilabel(app.UIFigure);
            app.MessagenumEditFieldLabel.Tooltip = {'Number of messages to be sent per frame'};
            app.MessagenumEditFieldLabel.Text = 'Message num';
            app.MessagenumEditField = uieditfield(app.UIFigure, 'numeric');
            app.MessagenumEditField.Value = 10;
            app.StartTransmitButton = uibutton(app.UIFigure, 'push');
            app.StartTransmitButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopTransmit, true);
            app.StartTransmitButton.Text = {'Start'; 'Transmit'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FSKTransmitterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end